classdef FarField_r0 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        FarFieldDiffractionModelLabel  matlab.ui.control.Label
        ApertureTypeDropDownLabel      matlab.ui.control.Label
        ApertureTypeDropDown           matlab.ui.control.DropDown
        RadiusEditFieldLabel           matlab.ui.control.Label
        RadiusEditField                matlab.ui.control.NumericEditField
        SideLengthEditFieldLabel       matlab.ui.control.Label
        SideLengthEditField            matlab.ui.control.NumericEditField
        PlotButton                     matlab.ui.control.Button
        FarField                       matlab.ui.control.UIAxes
        CrossSection                   matlab.ui.control.UIAxes
        Aperture                       matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: ApertureTypeDropDown
        function ApertureTypeDropDownValueChanged(app, event)
            switch app.ApertureTypeDropDown.Value
                case 'Circle'
                    app.SideLengthEditFieldLabel.Visible = 'off';
                    app.SideLengthEditField.Visible = 'off';
                    app.RadiusEditFieldLabel.Text = 'Radius'; 
                case 'Square'
                    app.SideLengthEditFieldLabel.Visible = 'on';
                    app.SideLengthEditField.Visible = 'on';
                    app.RadiusEditFieldLabel.Text = 'Length';
                    app.SideLengthEditFieldLabel.Text = 'Width';
                case 'Rectangle'
                    app.SideLengthEditFieldLabel.Visible = 'on';
                    app.SideLengthEditField.Visible = 'on';
                    app.RadiusEditFieldLabel.Text = 'Length';
                    app.SideLengthEditFieldLabel.Text = 'Width';
                case 'Right Triangle'
                    app.SideLengthEditFieldLabel.Visible = 'on';
                    app.SideLengthEditField.Visible = 'on';
                    app.RadiusEditFieldLabel.Text = 'Length';
                    app.SideLengthEditFieldLabel.Text = 'Height';
                case 'Single Slit'
                    app.SideLengthEditFieldLabel.Visible = 'off';
                    app.SideLengthEditField.Visible = 'off';
                    app.RadiusEditFieldLabel.Text = 'Slit Height';
                    app.SideLengthEditFieldLabel.Text = 'N/A';
                case 'Double Slit'
                    app.SideLengthEditFieldLabel.Visible = 'on';
                    app.SideLengthEditField.Visible = 'on';
                    app.RadiusEditFieldLabel.Text = 'Slit Height';
                    app.SideLengthEditFieldLabel.Text = 'Slit Spacing';
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FarFieldDiffractionModelLabel = uilabel(app.UIFigure);
            app.FarFieldDiffractionModelLabel.Text = 'Far-Field Diffraction Model';
            app.ApertureTypeDropDownLabel = uilabel(app.UIFigure);
            app.ApertureTypeDropDownLabel.Text = 'Aperture Type';
            app.ApertureTypeDropDown = uidropdown(app.UIFigure);
            app.ApertureTypeDropDown.Items = {'Circle', 'Square', 'Rectangle', 'Right Triangle', 'Single Slit', 'Double Slit'};
            app.ApertureTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @ApertureTypeDropDownValueChanged, true);
            app.ApertureTypeDropDown.Value = 'Circle';
            app.RadiusEditFieldLabel = uilabel(app.UIFigure);
            app.RadiusEditFieldLabel.Text = 'Radius';
            app.RadiusEditField = uieditfield(app.UIFigure, 'numeric');
            app.SideLengthEditFieldLabel = uilabel(app.UIFigure);
            app.SideLengthEditFieldLabel.Visible = 'off';
            app.SideLengthEditFieldLabel.Text = 'Side Length';
            app.SideLengthEditField = uieditfield(app.UIFigure, 'numeric');
            app.SideLengthEditField.Visible = 'off';
            app.PlotButton = uibutton(app.UIFigure, 'push');
            app.PlotButton.Text = 'Plot';
            app.FarField = uiaxes(app.UIFigure);
            title(app.FarField, 'Far Field Diffraction')
            xlabel(app.FarField, 'X')
            ylabel(app.FarField, 'Y')
            zlabel(app.FarField, 'Z')
            app.CrossSection = uiaxes(app.UIFigure);
            title(app.CrossSection, 'Cross Section of Y-axis')
            xlabel(app.CrossSection, 'X')
            ylabel(app.CrossSection, 'Y')
            zlabel(app.CrossSection, 'Z')
            app.Aperture = uiaxes(app.UIFigure);
            title(app.Aperture, 'Aperture')
            zlabel(app.Aperture, 'Z')
            app.Aperture.XTick = [];
            app.Aperture.YTick = [];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FarField_r0

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end